classdef TracePlateApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ImageAxes              matlab.ui.control.UIAxes
        LoadButton             matlab.ui.control.Button
        UserMsg                matlab.ui.control.Label
        ExamplesDropDownLabel  matlab.ui.control.Label
        ExamplesDropDown       matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            % Set proper filename for Dave or Randy
            if strcmp(imagefile,'Randy')
                imagefile = "data\ForTesting\RandyShort.mp4";
            elseif strcmp(imagefile,'Dave')
                imagefile = "data\ForTesting\DaveShort.mp4";
            end
                   
            % Process and display the frames
            vid = liftVid(imagefile);
            vid.nextFrame = 0;
            while vid.nextFrame >= 0
                img = processFrameByFrame(vid);
                imagesc(app.ImageAxes,img);
                app.UserMsg.Text = "Processing Frame #: " + vid.nextFrame;
                drawnow;
            end
            app.UserMsg.Text = "Done. Choose another video.";
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
        end

        % Value changed function: ExamplesDropDown
        function DropDownValueChanged(app, event)
            
            % Load one of the example videos
            if app.ExamplesDropDown.Value == "Dave"
                app.UserMsg.Text = "Loading Video";
                drawnow;
                updateimage(app, "Dave");                
            elseif app.ExamplesDropDown.Value == "Randy"        
                app.UserMsg.Text = "Loading Video";
                drawnow;
                updateimage(app, "Randy");                
            else
                app.UserMsg.Text = "Example not found. Choose another video";
            end
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.mp4'};
            [f, p] = uigetfile(filterspec, "Select Video File");
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               app.UserMsg.Text = "Loading Video";
               drawnow;
               updateimage(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'BarTrace';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Video';
            app.UserMsg = uilabel(app.UIFigure);
            app.UserMsg.Text = 'Select video using the Examples or Load button';
            app.ExamplesDropDownLabel = uilabel(app.UIFigure);
            app.ExamplesDropDownLabel.Text = 'Examples:';
            app.ExamplesDropDown = uidropdown(app.UIFigure);
            app.ExamplesDropDown.Items = {'Dave', 'Randy'};
            app.ExamplesDropDown.ItemsData = {'Dave', 'Randy'};
            app.ExamplesDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.ExamplesDropDown.Value = 'Dave';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TracePlateApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end